classdef DrillSpeedClassifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DrillSpeedClassifierUIFigure    matlab.ui.Figure
        LSTM_axes                       matlab.ui.control.UIAxes
        KNN_axes                        matlab.ui.control.UIAxes
        StartButton                     matlab.ui.control.Button
        DataFilteringSwitchLabel        matlab.ui.control.Label
        DataFilteringSwitch             matlab.ui.control.Switch
        FeatureSelectionLabel           matlab.ui.control.Label
        FeatureSelectionSwitch          matlab.ui.control.Switch
        PostprocessingSwitchLabel       matlab.ui.control.Label
        PostprocessingSwitch            matlab.ui.control.Switch
        StopButton                      matlab.ui.control.Button
        AccuracyEditFieldLabel          matlab.ui.control.Label
        AccuracyEditField_KNN           matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        AvgPredictionTimeEditFieldLabel  matlab.ui.control.Label
        AvgPredictionTimeEditField_KNN  matlab.ui.control.NumericEditField
        VotingPoolSliderLabel           matlab.ui.control.Label
        VotingPoolSlider                matlab.ui.control.Slider
        Label_2                         matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        AccuracyEditField_2Label        matlab.ui.control.Label
        AccuracyEditField_LSTM          matlab.ui.control.NumericEditField
        AvgPredictionTimeEditField_2Label  matlab.ui.control.Label
        AvgPredictionTimeEditField_LSTM  matlab.ui.control.NumericEditField
        KNNLabel                        matlab.ui.control.Label
        LSTMLabel                       matlab.ui.control.Label
    end

    
    properties (Access = public)
        selectedData_KNN
        selectedData_LSTM
        selectedModel_KNN
        selectedModel_LSTM
        stopFlag = false
        simRunning = false
    end
    
    methods (Access = public)
        
        function simulate(app)
            
            app.simRunning = true;
            
            % Select and load data and model
            if strcmp(app.DataFilteringSwitch.Value,'Original Data')
                
                if strcmp(app.FeatureSelectionSwitch.Value,'ManuallySelected')
                    app.selectedData_KNN = load('unfilt_MS_features_KNN').feat_unfilt_test;
                    app.selectedModel_KNN = load('unfilt_MS_model_KNN').trainedClassifier;
                    app.selectedData_LSTM.feat = load('unfilt_MS_features_LSTM');
                    app.selectedModel_LSTM.net = load('unfilt_MS_model_LSTM').netNP;
                elseif strcmp(app.FeatureSelectionSwitch.Value,'AudioFeatureExtractor')
                    app.selectedData_KNN = load('unfilt_AFE_features_KNN').feat_unfilt_test;
                    app.selectedModel_KNN = load('unfilt_AFE_model_KNN').trainedClassifier;
                    app.selectedData_LSTM.feat = load('unfilt_AFE_features_LSTM');
                    app.selectedModel_LSTM.net = load('unfilt_AFE_model_LSTM').netNP;
                end
                
            elseif strcmp(app.DataFilteringSwitch.Value,'Filtered Data')
                
                if strcmp(app.FeatureSelectionSwitch.Value,'ManuallySelected')
                    app.selectedData_KNN = load('filt_MS_features_KNN').feat_filt_test;
                    app.selectedModel_KNN = load('filt_MS_model_KNN').trainedClassifier;
                    app.selectedData_LSTM.feat = load('filt_MS_features_LSTM');
                    app.selectedModel_LSTM.net = load('filt_MS_model_LSTM').netNP;
                elseif strcmp(app.FeatureSelectionSwitch.Value,'AudioFeatureExtractor')
                    app.selectedData_KNN = load('filt_AFE_features_KNN').feat_filt_test;
                    app.selectedModel_KNN = load('filt_AFE_model_KNN').trainedClassifier;
                    app.selectedData_LSTM.feat = load('filt_AFE_features_LSTM');
                    app.selectedModel_LSTM.net = load('filt_AFE_model_LSTM').netNP;
                end
                
            end
            
            % Sort rows to order data according to velocity labels
            app.selectedData_KNN = sortrows(app.selectedData_KNN,'velocity');
            app.selectedData_KNN = app.selectedData_KNN(1:1:end,:);
            
            % Data are already sorted
%             selectedData_LSTM_table = table(app.selectedData_LSTM.feat.featuresX',app.selectedData_LSTM.feat.featuresY');
%             selectedData_LSTM_table = sortrows(selectedData_LSTM_table,'Var2');
%             app.selectedData_LSTM.feat.featuresX = selectedData_LSTM_table.Var1';
%             app.selectedData_LSTM.feat.featuresY = selectedData_LSTM_table.Var2';
%             app.selectedData_LSTM = app.selectedData_LSTM(:,1:1:end);
            
            
            %%%%% LSTM %%%%%
            % Execute simulation and plot values
            LSTMplot_predicted = scatter(app.LSTM_axes,nan,nan,10,'MarkerFaceColor','r','MarkerEdgeColor','r','LineWidth',0.1);
            hold(app.LSTM_axes,'on')
            LSTMplot_actual = scatter(app.LSTM_axes,nan,nan,5,'s','MarkerEdgeColor','b','LineWidth',0.3);
            legend(app.LSTM_axes,'predicted','actual','Location','southeast')
            hold(app.LSTM_axes,'off')
            
            if strcmp(app.PostprocessingSwitch.Value,'Off')
                j = 1;
                xValues = 1:size(app.selectedData_LSTM.feat.featuresX,2);
                time = [];
                tic
                predicted = classify(app.selectedModel_LSTM.net,app.selectedData_LSTM.feat.featuresX);
                t_pred = toc;
                t_pred = t_pred/numel(predicted);
                while j < size(app.selectedData_LSTM.feat.featuresX,2) && app.stopFlag == false
                    tic
                    set(LSTMplot_predicted,'XData',xValues(1:j),'YData',predicted(1:j));
                    set(LSTMplot_actual,'XData',xValues(1:j),'YData',app.selectedData_LSTM.feat.featuresY(1:j));
                    drawnow;
                    
                    % Display temporary accuracy
                    accuracy = sum(predicted(1:j) == app.selectedData_LSTM.feat.featuresY(1:j))/ ...
                               numel(app.selectedData_LSTM.feat.featuresY(1:j))*100;
                    set(app.AccuracyEditField_LSTM,'Value',accuracy); 
                    
                    j = j+1;
                    t = toc;
                    time = [time (t+t_pred)];
                    set(app.AvgPredictionTimeEditField_LSTM,'Value',mean(time));
                end
                
            elseif strcmp(app.PostprocessingSwitch.Value,'On')
                categ = categorical(app.LSTM_axes.YTickLabel);
                map = containers.Map(cellstr(categ),1:10);
                voting_pool = app.VotingPoolSlider.Value;
                j = 1;
                voted_predicted = [];
                xValues = 1:size(app.selectedData_LSTM.feat.featuresX,2);
                time = [];
                tic
                predicted = classify(app.selectedModel_LSTM.net,app.selectedData_LSTM.feat.featuresX);
                t_pred = toc;
                while j < size(app.selectedData_LSTM.feat.featuresX,2) && app.stopFlag == false
                    if mod(j,voting_pool) == 1 
                        tic
                    end
                    if mod(j,voting_pool) == 0
                        [~,pos] = max(countcats(predicted(j-(voting_pool-1):j)));
                        voted = categ(pos);
                        voted_predicted = [voted_predicted; voted];
                        set(LSTMplot_actual,'XData',xValues(1:j),'YData',app.selectedData_LSTM.feat.featuresY(1:j));
                        set(LSTMplot_predicted,'XData',xValues(ceil(voting_pool/2):voting_pool:j),'YData',cell2mat(values(map,cellstr(voted_predicted(1:end)))));
                        drawnow;
                        
                        % Display temporary accuracy
                        accuracy = sum(voted_predicted(1:end)' == app.selectedData_LSTM.feat.featuresY(ceil(voting_pool/2):voting_pool:j))/ ...
                                   numel(app.selectedData_LSTM.feat.featuresY(ceil(voting_pool/2):voting_pool:j))*100;
                        set(app.AccuracyEditField_LSTM,'Value',accuracy);
                        t = toc;
                        time = [time (t+t_pred)];
                        set(app.AvgPredictionTimeEditField_LSTM,'Value',mean(time));
                    end
                    j = j+1;
                end
                
            end
            %%%%% END LSTM %%%%%
            
            
            %%%%% KNN %%%%%
            % Execute simulation and plot values
            KNNplot_predicted = scatter(app.KNN_axes,nan,nan,10,'MarkerFaceColor','r','MarkerEdgeColor','r','LineWidth',0.1);
            hold(app.KNN_axes,'on')
            KNNplot_actual = scatter(app.KNN_axes,nan,nan,5,'s','MarkerEdgeColor','b','LineWidth',0.3);
            legend(app.KNN_axes,'predicted','actual','Location','southeast')
            hold(app.KNN_axes,'off')
            
            if strcmp(app.PostprocessingSwitch.Value,'Off')
                j = 1;
                predicted = categorical();
                xValues = 1:height(app.selectedData_KNN);
                time = [];
                while j < height(app.selectedData_KNN) && app.stopFlag == false
                    tic
                    pred_vel = app.selectedModel_KNN.predictFcn(app.selectedData_KNN(j,:));
                    predicted = [predicted pred_vel];
                    set(KNNplot_predicted,'XData',xValues(1:j),'YData',predicted(1:j));
                    set(KNNplot_actual,'XData',xValues(1:j),'YData',app.selectedData_KNN.velocity(1:j));
                    drawnow;
                    
                    % Display temporary accuracy
                    accuracy = sum(predicted(1:j)' == app.selectedData_KNN.velocity(1:j))/numel(app.selectedData_KNN.velocity(1:j))*100;
                    set(app.AccuracyEditField_KNN,'Value',accuracy); 
                    
                    j = j+1;
                    t = toc;
                    time = [time t];
                    set(app.AvgPredictionTimeEditField_KNN,'Value',mean(time));
                end
                
            elseif strcmp(app.PostprocessingSwitch.Value,'On')
                categ = categorical(app.KNN_axes.YTickLabel);
                map = containers.Map(cellstr(categ),1:10);
                voting_pool = app.VotingPoolSlider.Value;
                j = 1;
                predicted = [];
                voted_predicted = [];
                xValues = 1:height(app.selectedData_KNN);
                time = [];
                while j < height(app.selectedData_KNN) && app.stopFlag == false
                    if mod(j,voting_pool) == 1 
                        tic
                    end
                    pred_vel = app.selectedModel_KNN.predictFcn(app.selectedData_KNN(j,:));
                    predicted = [predicted; pred_vel];
                    if mod(j,voting_pool) == 0
                        [~,pos] = max(countcats(predicted(j-(voting_pool-1):j)));
                        voted = categ(pos);
                        voted_predicted = [voted_predicted; voted];
                        set(KNNplot_actual,'XData',xValues(1:j),'YData',app.selectedData_KNN.velocity(1:j));
                        set(KNNplot_predicted,'XData',xValues(ceil(voting_pool/2):voting_pool:j),'YData',cell2mat(values(map,cellstr(voted_predicted(1:end)))));
                        drawnow;
                        
                        % Display temporary accuracy
                        accuracy = sum(voted_predicted(1:end) == app.selectedData_KNN.velocity(ceil(voting_pool/2):voting_pool:j))/ ...
                                   numel(app.selectedData_KNN.velocity(ceil(voting_pool/2):voting_pool:j))*100;
                        set(app.AccuracyEditField_KNN,'Value',accuracy);
                        t = toc;
                        time = [time t];
                        set(app.AvgPredictionTimeEditField_KNN,'Value',mean(time));
                    end
                    j = j+1;
                end
                
            end
            %%%%% END KNN %%%%%
   
            
            app.simRunning = false;
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Load default features and model (Unfiltered,MS)
            app.selectedData_KNN = load('unfilt_MS_features_KNN').feat_unfilt_test;
            app.selectedData_LSTM.feat = load('unfilt_MS_features_LSTM');
            app.selectedModel_KNN = load('unfilt_MS_model_KNN').trainedClassifier;
            app.selectedModel_LSTM.net = load('unfilt_MS_model_LSTM').netNP;
            
            % Define axes properties
            app.KNN_axes.YTick = 1:10;
            app.LSTM_axes.YTick = 1:10;
            
            category_array = {'V1_N: 560 RPM';'V2_N: 780 RPM';'V3_N: 1200 RPM';'V4_N: 1900 RPM';'V5_N: 3000 RPM'; ...
                              'V1_P: 560 RPM';'V2_P: 780 RPM';'V3_P: 1200 RPM';'V4_P: 1900 RPM';'V5_P: 3000 RPM'}';
            app.KNN_axes.YTickLabel = category_array;
            app.LSTM_axes.YTickLabel = category_array;
            
            app.KNN_axes.YLim = [1 10];
            app.LSTM_axes.YLim = [1 10];
                                    
            app.KNN_axes.XLim = [1 height(app.selectedData_KNN(1:1:end,:))];
            app.LSTM_axes.XLim = [1 size(app.selectedData_LSTM.feat.featuresX(:,1:1:end),2)];
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.stopFlag = false;
            if app.simRunning == false
                app.simulate
            end
        end

        % Value changed function: DataFilteringSwitch
        function DataFilteringSwitchValueChanged(app, event)
            app.DataFilteringSwitch.Value = event.Value;
        end

        % Value changed function: FeatureSelectionSwitch
        function FeatureSelectionSwitchValueChanged(app, event)
            app.FeatureSelectionSwitch.Value = event.Value;
        end

        % Value changed function: PostprocessingSwitch
        function PostprocessingSwitchValueChanged(app, event)
            app.PostprocessingSwitch.Value = event.Value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopFlag = true;
            app.simRunning = false;
        end

        % Value changed function: VotingPoolSlider
        function VotingPoolSliderValueChanged(app, event)
            % Select odd number, in order to predict a central value from past and future predictions
            
            value = app.VotingPoolSlider.Value;
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            app.VotingPoolSlider.Value = event.Source.MajorTicks(minIdx);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DrillSpeedClassifierUIFigure = uifigure('Visible', 'off');
            app.DrillSpeedClassifierUIFigure.Name = 'Drill Speed Classifier';
            app.LSTM_axes = uiaxes(app.DrillSpeedClassifierUIFigure);
            title(app.LSTM_axes, 'LSTM Neural Network')
            xlabel(app.LSTM_axes, 'sample number')
            ylabel(app.LSTM_axes, 'velocity class')
            app.KNN_axes = uiaxes(app.DrillSpeedClassifierUIFigure);
            title(app.KNN_axes, 'KNN Classifier')
            xlabel(app.KNN_axes, 'sample number')
            ylabel(app.KNN_axes, 'velocity class')
            app.StartButton = uibutton(app.DrillSpeedClassifierUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DataFilteringSwitchLabel = uilabel(app.DrillSpeedClassifierUIFigure);
            app.DataFilteringSwitchLabel.Text = 'Data Filtering';
            app.DataFilteringSwitch = uiswitch(app.DrillSpeedClassifierUIFigure, 'slider');
            app.DataFilteringSwitch.Items = {'Original Data', 'Filtered Data'};
            app.DataFilteringSwitch.ValueChangedFcn = createCallbackFcn(app, @DataFilteringSwitchValueChanged, true);
            app.DataFilteringSwitch.Value = 'Original Data';
            app.FeatureSelectionLabel = uilabel(app.DrillSpeedClassifierUIFigure);
            app.FeatureSelectionLabel.Text = 'Feature Selection';
            app.FeatureSelectionSwitch = uiswitch(app.DrillSpeedClassifierUIFigure, 'slider');
            app.FeatureSelectionSwitch.Items = {'ManuallySelected', 'AudioFeatureExtractor'};
            app.FeatureSelectionSwitch.ValueChangedFcn = createCallbackFcn(app, @FeatureSelectionSwitchValueChanged, true);
            app.FeatureSelectionSwitch.Value = 'ManuallySelected';
            app.PostprocessingSwitchLabel = uilabel(app.DrillSpeedClassifierUIFigure);
            app.PostprocessingSwitchLabel.Text = 'Post-processing';
            app.PostprocessingSwitch = uiswitch(app.DrillSpeedClassifierUIFigure, 'slider');
            app.PostprocessingSwitch.ValueChangedFcn = createCallbackFcn(app, @PostprocessingSwitchValueChanged, true);
            app.StopButton = uibutton(app.DrillSpeedClassifierUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.AccuracyEditFieldLabel = uilabel(app.DrillSpeedClassifierUIFigure);
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.AccuracyEditField_KNN = uieditfield(app.DrillSpeedClassifierUIFigure, 'numeric');
            app.AccuracyEditField_KNN.Limits = [0 100];
            app.AccuracyEditField_KNN.Editable = 'off';
            app.Label = uilabel(app.DrillSpeedClassifierUIFigure);
            app.Label.Text = '%';
            app.sLabel = uilabel(app.DrillSpeedClassifierUIFigure);
            app.sLabel.Text = 's';
            app.AvgPredictionTimeEditFieldLabel = uilabel(app.DrillSpeedClassifierUIFigure);
            app.AvgPredictionTimeEditFieldLabel.Text = 'Avg. Prediction Time';
            app.AvgPredictionTimeEditField_KNN = uieditfield(app.DrillSpeedClassifierUIFigure, 'numeric');
            app.AvgPredictionTimeEditField_KNN.Limits = [0 100];
            app.AvgPredictionTimeEditField_KNN.Editable = 'off';
            app.VotingPoolSliderLabel = uilabel(app.DrillSpeedClassifierUIFigure);
            app.VotingPoolSliderLabel.Text = 'Voting Pool';
            app.VotingPoolSlider = uislider(app.DrillSpeedClassifierUIFigure);
            app.VotingPoolSlider.Limits = [5 29];
            app.VotingPoolSlider.MajorTicks = [5 11 17 23 29];
            app.VotingPoolSlider.ValueChangedFcn = createCallbackFcn(app, @VotingPoolSliderValueChanged, true);
            app.VotingPoolSlider.MinorTicks = [];
            app.VotingPoolSlider.Value = 17;
            app.Label_2 = uilabel(app.DrillSpeedClassifierUIFigure);
            app.Label_2.Text = '%';
            app.sLabel_2 = uilabel(app.DrillSpeedClassifierUIFigure);
            app.sLabel_2.Text = 's';
            app.AccuracyEditField_2Label = uilabel(app.DrillSpeedClassifierUIFigure);
            app.AccuracyEditField_2Label.Text = 'Accuracy';
            app.AccuracyEditField_LSTM = uieditfield(app.DrillSpeedClassifierUIFigure, 'numeric');
            app.AccuracyEditField_LSTM.Limits = [0 100];
            app.AccuracyEditField_LSTM.Editable = 'off';
            app.AvgPredictionTimeEditField_2Label = uilabel(app.DrillSpeedClassifierUIFigure);
            app.AvgPredictionTimeEditField_2Label.Text = 'Avg. Prediction Time';
            app.AvgPredictionTimeEditField_LSTM = uieditfield(app.DrillSpeedClassifierUIFigure, 'numeric');
            app.AvgPredictionTimeEditField_LSTM.Limits = [0 100];
            app.AvgPredictionTimeEditField_LSTM.Editable = 'off';
            app.KNNLabel = uilabel(app.DrillSpeedClassifierUIFigure);
            app.KNNLabel.Text = 'KNN';
            app.LSTMLabel = uilabel(app.DrillSpeedClassifierUIFigure);
            app.LSTMLabel.Text = 'LSTM';
            % Show the figure after all components are created
            app.DrillSpeedClassifierUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DrillSpeedClassifier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DrillSpeedClassifierUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DrillSpeedClassifierUIFigure)
        end
    end
end